classdef wykreslacz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_12                     matlab.ui.container.Panel
        Panel_10                     matlab.ui.container.Panel
        yCheckBox                    matlab.ui.control.CheckBox
        xCheckBox                    matlab.ui.control.CheckBox
        SymetrycznyzakresLabel       matlab.ui.control.Label
        Panel_9                      matlab.ui.container.Panel
        chartsMatchCheckBox          matlab.ui.control.CheckBox
        SkalaosiydlaobuwykreswPanel  matlab.ui.container.Panel
        yAutoSwitch                  matlab.ui.control.Switch
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        OaplikacjiButton             matlab.ui.control.Button
        Panel_11                     matlab.ui.container.Panel
        formulaEditField             matlab.ui.control.EditField
        ProszwprowadzifunkcjLabel    matlab.ui.control.Label
        xButton                      matlab.ui.control.Button
        actualizationButton          matlab.ui.control.Button
        FxLabel                      matlab.ui.control.Label
        Panel_7                      matlab.ui.container.Panel
        PARAMETRYLabel               matlab.ui.control.Label
        Panel_6                      matlab.ui.container.Panel
        Panel_4                      matlab.ui.container.Panel
        dFdxMinEditField             matlab.ui.control.NumericEditField
        dFdxMaxEditField             matlab.ui.control.NumericEditField
        yMinEditField                matlab.ui.control.NumericEditField
        yMaxEditField                matlab.ui.control.NumericEditField
        ZakresosiyLabel              matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        xMaxEditField                matlab.ui.control.NumericEditField
        xMinEditField                matlab.ui.control.NumericEditField
        ZakresosixLabel              matlab.ui.control.Label
        UIAxesD                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        speedEditField               matlab.ui.control.NumericEditField
        Szybkozmian1zakres10sLabel   matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        downLamp                     matlab.ui.control.Lamp
        upLamp                       matlab.ui.control.Lamp
        directionSwitch              matlab.ui.control.Switch
        animationButton              matlab.ui.control.StateButton
        bPanel                       matlab.ui.container.Panel
        bGroupButton                 matlab.ui.control.StateButton
        bSlider                      matlab.ui.control.Slider
        bAnimationButton             matlab.ui.control.StateButton
        bMin                         matlab.ui.control.NumericEditField
        bMax                         matlab.ui.control.NumericEditField
        cPanel                       matlab.ui.container.Panel
        cGroupButton                 matlab.ui.control.StateButton
        cSlider                      matlab.ui.control.Slider
        cAnimationButton             matlab.ui.control.StateButton
        cMin                         matlab.ui.control.NumericEditField
        cMax                         matlab.ui.control.NumericEditField
        aPanel                       matlab.ui.container.Panel
        aGroupButton                 matlab.ui.control.StateButton
        aAnimationButton             matlab.ui.control.StateButton
        aMin                         matlab.ui.control.NumericEditField
        aSlider                      matlab.ui.control.Slider
        aMax                         matlab.ui.control.NumericEditField
        PrzykladyDropDown            matlab.ui.control.DropDown
        PrzykladyDropDownLabel       matlab.ui.control.Label
        WYKRELACZLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        formula % string, który przechowuje wzór funkcji
        formulaD % string, który przechowuje wzór pochodnej funkcji
        % tu są przechowywane parametry (muszą być inicjalizowane, żeby na pewno były macierzami
        aGlobal = 0;
        bGlobal = 0;
        cGlobal = 0;
    end
  
    methods (Access = private)

    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)

        % funkcja zczytująca wzór funkcji matematycznej podany przez użytkownika
        function formulaUpdate(app)
            % pobiera stringa ze wzorem funkcji z pola tekstowego
            app.formula = app.formulaEditField.Value;
            % dodaje opis do wykresu
            chartName(app);
            % poprawia operatory zwykłe na macierzowe
            formulaCorrection(app);

            % liczy pochodną, nazywa nią wykres i poprawia
            derivative(app);
            chartNameD(app);
            formulaCorrectionD(app);

            % tworzy argumenty dla funkcji
            x_min = app.xMinEditField.Value;
            x_max = app.xMaxEditField.Value;
            x = linspace(x_min, x_max, 500);
            % aktualizuje wykresy funkcji i jej pochodnej
            updatePlot(app, x);
            updatePlotD(app, x);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % zamienia wszystkie normalnmie operacje na macierzowe w tekście
        % wpisanym przez użytkownika
        function formulaCorrection(app)
            app.formula = replace(app.formula, ' ', '');
            app.formula = replace(app.formula, '*', '.*');
            app.formula = replace(app.formula, '/', './');
            app.formula = replace(app.formula, '^', '.^');
        end
        % zamienia wszystkie normalnmie operacje na macierzowe w tekście
        % pochodnej
        function formulaCorrectionD(app)
            app.formulaD = replace(app.formulaD, ' ', '');
            app.formulaD = replace(app.formulaD, '*', '.*');
            app.formulaD = replace(app.formulaD, '/', './');
            app.formulaD = replace(app.formulaD, '^', '.^');
        end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % aktualizuje wykres
        function updatePlot(app, x)
                cla(app.UIAxes);
                % pobiera wartości parametrów ze zmiennych globalnych
                a = app.aGlobal;
                b = app.bGlobal;
                c = app.cGlobal;
                % tworzy funkcję z podanego stringa
                func = str2func(['@(x, a, b, c)', app.formula]);
                y = func(x, a, b, c);
                if app.yAutoSwitch.ValueIndex == 1
                    yLimits(app);
                else
                    ymin = min(y(:))-0.001;
                    ymax = max(y(:))+0.001;
                    if isa(ymin, 'double') && isa(ymax, 'double') && isreal(ymin) && isreal(ymax)
                        app.UIAxes.YLim = [ymin ymax];
                    end
                end
                plot(app.UIAxes, x, y, 'Color', '#800080');
                drawnow;
        end
        % aktualizuje wykres pochodnej
        function updatePlotD(app, x)
                cla(app.UIAxesD);
                % pobiera wartości parametrów ze zmiennych globalnych
                a = app.aGlobal;
                b = app.bGlobal;
                c = app.cGlobal;
                % tworzy funkcję z podanego stringa
                func = str2func(['@(x, a, b, c)', app.formulaD]);
                y = func(x, a, b, c);
                if app.yAutoSwitch.ValueIndex == 1
                    dFdxLimits(app);
                else
                    ymin = min(y(:))-0.001;
                    ymax = max(y(:))+0.001;
                    if isa(ymin, 'double') && isa(ymax, 'double') && isreal(ymin) && isreal(ymax)
                        app.UIAxesD.YLim = [ymin ymax];
                    end
                end
                plot(app.UIAxesD, x, y, 'Color', '#800080');
                drawnow;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function animation(app)
            % zmiana parametru a w czasie
            formulaUpdate(app)
         
                % pobieranie granic zakresów
                a_max = app.aSlider.Limits(2);
                a_min = app.aSlider.Limits(1);
                b_max = app.bSlider.Limits(2);
                b_min = app.bSlider.Limits(1);
                c_max = app.cSlider.Limits(2);
                c_min = app.cSlider.Limits(1);
    
                % sprawdza, czy parametry są aktywne
                if_a = app.aAnimationButton.Value;
                if_b = app.cAnimationButton.Value;
                if_c = app.bAnimationButton.Value;
    
                % ustawia licznik i według najwiekszego aktywnego parametru
                % ustawia licznik i względem pierwszego aktywnego parametru
                i_tmp_a = app.aSlider.Value/(a_max - a_min);
                i_tmp_b = app.bSlider.Value/(b_max - b_min);
                i_tmp_c = app.cSlider.Value/(c_max - c_min);
                if if_a
                    i = i_tmp_a;
                elseif if_b
                    i = i_tmp_b;
                elseif if_c
                    i = i_tmp_b;
                end
                % ustawia b lub c, jeśli są większe od a
                if if_b
                    i_tmp = i_tmp_b;
                    if i_tmp > i 
                        i = i_tmp;
                    end
                elseif if_c
                    i_tmp = i_tmp_c;
                    if i_tmp > i
                        i = i_tmp;
                    end
                end 
    
                % tworzy argumenty dla dunkcji
                x_min = app.xMinEditField.Value;
                x_max = app.xMaxEditField.Value;
                x = linspace(x_min, x_max, 500);

            while 1
                duration = tic();
                % przerywa animację
                if app.animationButton.Value == 0
                    app.animationButton.Text = 'Animacja {OFF}';
                    break
                end    

                % na podstawie switcha determinuje zwrto predkości (1 to
                % up, 2 to down)
                if app.directionSwitch.ValueIndex == 1
                    sign = 1;
                else
                    sign = -1;
                end

                % szybkość
                speed = app.speedEditField.Value;
                i = i + sign*speed/1000;

                % ustawia na minimum/maksimum, kiedy wartość przekroczy zakres
                if i > 1
                    i = 0;
                end
                if i < 0
                    i = 1;
                end
                
                % zmiana parametrów pod warunkiem że są odznaczone
                % parametr a
                if_a = app.aAnimationButton.Value;
                if if_a == 1
                    tmp_a = i*(a_max - a_min) + a_min;
                    app.aGlobal = tmp_a;
                    app.aSlider.Value = tmp_a;
                end
                % parametr b
                if_b = app.bAnimationButton.Value;
                if if_b == 1
                    tmp_b = i*(b_max - b_min) + b_min;
                    app.bGlobal = tmp_b;
                    app.bSlider.Value = tmp_b;
                end
                % parametr c
                if_c = app.cAnimationButton.Value;
                if if_c == 1
                    tmp_c = i*(c_max - c_min) + c_min;
                    app.cGlobal = tmp_c;
                    app.cSlider.Value = tmp_c;
                end

                % odświeżenie wykresu
                updatePlot(app, x);
                updatePlotD(app, x);
                drawnow;
                
                % blokuje iterację, dopóki nie upłynie 0.02 s
                while toc(duration) < 0.02
                end
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ustwaia granice x na wykresie
        function xLimits(app)
            % pobiera aktualnie zakresy wpisane do pól obok osi
            x_min = app.xMinEditField.Value;
            x_max = app.xMaxEditField.Value;
            % aktualizuje, tylko kiedy górny jest większy od dolnego
            if x_max > x_min
                app.UIAxes.XLim = [x_min x_max];
                app.UIAxesD.XLim = [x_min x_max];
            end
        end

        % ustwaia granice y na wykresie
        function yLimits(app)
            % pobiera aktualnie zakresy wpisane do pól obok osi
            y_min = app.yMinEditField.Value;
            y_max = app.yMaxEditField.Value;
            % aktualizuje, tylko kiedy górny jest większy od dolnego
            if y_max > y_min
                app.UIAxes.YLim = [y_min y_max];
            end
            % dopasowanie granic drugiego wykresu, jeśli wybrana taka opcja
            if app.chartsMatchCheckBox.Value
                app.UIAxesD.YLim = app.UIAxes.YLim;
                app.dFdxMaxEditField.Value = app.yMaxEditField.Value;
                app.dFdxMinEditField.Value = app.yMinEditField.Value;
            end
        end

        % ustwaia granice y na wykresie pochidnych
        function dFdxLimits(app)
            % pobiera aktualnie zakresy wpisane do pól obok osi
            y_min = app.dFdxMinEditField.Value;
            y_max = app.dFdxMaxEditField.Value;
            % aktualizuje, tylko kiedy górny jest większy od dolnego
            if y_max > y_min
                app.UIAxesD.YLim = [y_min y_max];
            end
            % dopasowanie granic drugiego wykresu, jeśli wybrana taka opcja
            if app.chartsMatchCheckBox.Value
                app.UIAxes.YLim = app.UIAxesD.YLim;
                app.yMaxEditField.Value = app.dFdxMaxEditField.Value;
                app.yMinEditField.Value = app.dFdxMinEditField.Value;
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % umieszcza wzór funkcji nad wykresem
        function chartName(app)
            name = app.formulaEditField.Value;
            name = replace(name, ' ', '');
            name = replace(name, '*', '');
            name = replace(name, '^', '\^');
            name = replace(name, 'exp', 'e\^');
            name = ['F(x) = ' name];
            titleText = text('Parent', app.UIAxes, ...
                     'String', name, ...
                     'FontSize', 14, ...
                     'HorizontalAlignment', 'center', ...
                     'VerticalAlignment', 'bottom');
            app.UIAxes.Title = titleText;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % umieszcza wzór funkcji nad wykresem pochodnej
        function chartNameD(app)
            name = app.formulaD;
            name = replace(name, ' ', '');
            name = replace(name, '*', '');
            name = replace(name, '^', '\^');
            name = replace(name, 'exp', 'e\^');
            name = ['F''(x) = ' name];
            titleText = text('Parent', app.UIAxesD, ...
                     'String', name, ...
                     'FontSize', 14, ...
                     'HorizontalAlignment', 'center', ...
                     'VerticalAlignment', 'bottom');
            app.UIAxesD.Title = titleText;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function derivative(app)
            syms x;
            func = str2func(['@(x, a, b, c)', app.formula]);
            dfdx = diff(func, x);
            app.formulaD = char(dfdx);
            %app.TestEditField.Value = char(dfdx);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ustawienie tego samego zakresu y dla obu wykresów
        function chartsMatch(app)
            app.UIAxesD.YLim = app.UIAxes.YLim;
            app.dFdxMaxEditField.Value = app.yMaxEditField.Value;
            app.dFdxMinEditField.Value = app.yMinEditField.Value;            
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ustawienie symetrycznego zakresu x
            function xSym(app)
                % pobiera moduły granic zakresu osi
                x_min = abs(app.xMinEditField.Value);
                x_max = abs(app.xMaxEditField.Value);
                % ustawia symetryczne granice wg większego modułu
                if x_max >= x_min
                    x = x_max;
                else
                    x = x_min;
                end
                app.xMaxEditField.Value = x;
                app.xMinEditField.Value = -x;
                xLimits(app);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ustawienie symetrycznego zakresu y dla funkcji
            function ySym(app)
                % pobiera moduły granic zakresu osi
                y_min = abs(app.yMinEditField.Value);
                y_max = abs(app.yMaxEditField.Value);
                % ustawia symetryczne granice wg większego modułu
                if y_max >= y_min
                    y = y_max;
                else
                    y = y_min;
                end
                app.yMaxEditField.Value = y;
                app.yMinEditField.Value = -y;
                yLimits(app);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ustawienie symetrycznego zakresu y dla pochodnej
            function dFdxSym(app)
                % pobiera moduły granic zakresu osi
                y_min = abs(app.dFdxMinEditField.Value);
                y_max = abs(app.dFdxMaxEditField.Value);
                % ustawia symetryczne granice wg większego modułu
                if y_max >= y_min
                    y = y_max;
                else
                    y = y_min;
                end
                app.dFdxMaxEditField.Value = y;
                app.dFdxMinEditField.Value = -y;
                dFdxLimits(app);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % dopasowanie granic suwaka a
        function aSliderMatch(app)
            % pobiera górną i dolną granicę suwaka
            up = app.aMax.Value;
            down = app.aMin.Value;
            if up > down % ustawia granice, tylko jeśli zostały spisane poprawnie
                app.aSlider.Limits = [down up];
            end
            % przerwanie animacji
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
        end

        % dopasowanie granic suwaka b
        function bSliderMatch(app)
            % pobiera górną i dolną granicę suwaka
            up = app.bMax.Value;
            down = app.bMin.Value;
            if up > down % ustawia granice, tylko jeśli zostały spisane poprawnie
                app.bSlider.Limits = [down up];
            end
            % przerwanie animacji
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
        end

        % dopasowanie granic suwaka c
        function cSliderMatch(app)
            % pobiera górną i dolną granicę suwaka
            up = app.cMax.Value;
            down = app.cMin.Value;
            if up > down % ustawia granice, tylko jeśli zostały spisane poprawnie
                app.cSlider.Limits = [down up];
            % przerwanie animacji
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function packageSet(app, value)
            % zmienne do przechowywania ustawień pozycji suwaków
            aSliderValue = 0;
            bSliderValue = 0;
            cSliderValue = 0;
            % wyłącza wspólne ręczne przesuwanie parametrów
            app.aGroupButton.Value = 0;
            app.bGroupButton.Value = 0;
            app.cGroupButton.Value = 0;
            switch value
                case 1 % fala
                    % wzór
                    app.formulaEditField.Value = 'sin(b+a*x)*exp(-c*-x^2)';

                    % ustawienia
                    app.speedEditField.Value = 7;
                    app.directionSwitch.ValueIndex = 1;
                    app.xCheckBox.Value = 1;
                    app.yCheckBox.Value = 1;
                    app.chartsMatchCheckBox.Value = 0;
                    app.yAutoSwitch.ValueIndex = 1;

                    % parametry
                    % a
                    app.aMin.Value = -60;
                    app.aMax.Value = 60;
                    aSliderValue = -60;
                    app.aAnimationButton.Value = 1;
                    % b
                    app.bMin.Value = 0;
                    app.bMax.Value = 120;
                    bSliderValue = 0;
                    app.bAnimationButton.Value = 1;
                    % c
                    app.cMin.Value = -10;
                    app.cMax.Value = -1;
                    cSliderValue = -10;
                    app.cAnimationButton.Value = 1;
                    
                    % skale osi
                    % x
                    app.xMinEditField.Value = -1.5;
                    app.xMaxEditField.Value = 1.5;                    
                    % y funkcja
                    app.yMinEditField.Value = -1.1;
                    app.yMaxEditField.Value = 1.1;
                    % y pochodna
                    app.dFdxMinEditField.Value = -60;
                    app.dFdxMaxEditField.Value = 60;
                case 2 % rozkład Maxwella
                    % wzór
                    app.formulaEditField.Value = '(b/a)^(3/2)*x^2*exp(-0.5*b*x^2/a)';

                    % ustawienia
                    app.speedEditField.Value = 4;
                    app.directionSwitch.ValueIndex = 1;
                    app.xCheckBox.Value = 0;
                    app.yCheckBox.Value = 0;
                    app.chartsMatchCheckBox.Value = 0;
                    app.yAutoSwitch.ValueIndex = 1;

                    % parametry
                    % a
                    app.aMin.Value = 30;
                    app.aMax.Value = 400;
                    aSliderValue = 30;
                    app.aAnimationButton.Value = 1;
                    % b
                    app.bMin.Value = 10;
                    app.bMax.Value = 200;
                    bSliderValue = 100;
                    app.bAnimationButton.Value = 0;
                    % c
                    app.cMin.Value = -10;
                    app.cMax.Value = 10;
                    cSliderValue = 0;
                    app.cAnimationButton.Value = 0;
                    
                    % skale osi
                    % x
                    app.xMinEditField.Value = 0;
                    app.xMaxEditField.Value = 8;                    
                    % y funkcja
                    app.yMinEditField.Value = 0;
                    app.yMaxEditField.Value = 1.4;
                    % y pochodna
                    app.dFdxMinEditField.Value = -2;
                    app.dFdxMaxEditField.Value = 3;
                case 3 % rezonans
                    % wzór
                    app.formulaEditField.Value = '10*(((c/b)^2-x^2)^2+4*(a/b)^2*x^2)^-0.5';

                    % ustawienia
                    app.speedEditField.Value = 10;
                    app.directionSwitch.ValueIndex = 2;
                    app.xCheckBox.Value = 0;
                    app.yCheckBox.Value = 0;
                    app.chartsMatchCheckBox.Value = 0;
                    app.yAutoSwitch.ValueIndex = 2;

                    % parametry
                    % a
                    app.aMin.Value = 0;
                    app.aMax.Value = 5;
                    aSliderValue = 5;
                    app.aAnimationButton.Value = 1;
                    % b
                    app.bMin.Value = 1;
                    app.bMax.Value = 7;
                    bSliderValue = 3;
                    app.bAnimationButton.Value = 0;
                    % c
                    app.cMin.Value = 1;
                    app.cMax.Value = 10;
                    cSliderValue = 5;
                    app.cAnimationButton.Value = 0;
                    
                    % skale osi
                    % x
                    app.xMinEditField.Value = 0;
                    app.xMaxEditField.Value = 5;                    
                    % y funkcja
                    app.yMinEditField.Value = 0;
                    app.yMaxEditField.Value = 14;
                    % y pochodna
                    app.dFdxMinEditField.Value = -10;
                    app.dFdxMaxEditField.Value = 8;
                case 4 % ciasto
                    % wzór
                    app.formulaEditField.Value = '4*a^2/x^2*exp(2*a/x)/(exp(2*a/x)-1)^2';

                    % ustawienia
                    app.speedEditField.Value = 8;
                    app.directionSwitch.ValueIndex = 1;
                    app.xCheckBox.Value = 0;
                    app.yCheckBox.Value = 0;
                    app.chartsMatchCheckBox.Value = 0;
                    app.yAutoSwitch.ValueIndex = 1;

                    % parametry
                    % a
                    app.aMin.Value = 1;
                    app.aMax.Value = 100;
                    aSliderValue = 1;
                    app.aAnimationButton.Value = 1;
                    % b
                    app.bMin.Value = -10;
                    app.bMax.Value = 10;
                    bSliderValue = 0;
                    app.bAnimationButton.Value = 0;
                    % c
                    app.cMin.Value = -10;
                    app.cMax.Value = 10;
                    cSliderValue = 0;
                    app.cAnimationButton.Value = 0;
                    
                    % skale osi
                    % x
                    app.xMinEditField.Value = 0;
                    app.xMaxEditField.Value = 250;                    
                    % y funkcja
                    app.yMinEditField.Value = 0;
                    app.yMaxEditField.Value = 1;
                    % y pochodna
                    app.dFdxMinEditField.Value = 0;
                    app.dFdxMaxEditField.Value = 0.2;
                case 5 % dyfrakcja
                    % wzór
                    app.formulaEditField.Value = '(cos(a*sin(x)/c))^2*( sin(3.14*b*sin(x)/c)/(3.14*b*sin(x)/c) )^2';

                    % ustawienia
                    app.speedEditField.Value = 10;
                    app.directionSwitch.ValueIndex = 1;
                    app.xCheckBox.Value = 1;
                    app.yCheckBox.Value = 0;
                    app.chartsMatchCheckBox.Value = 0;
                    app.yAutoSwitch.ValueIndex = 2;

                    % parametry
                    % a
                    app.aMin.Value = 1;
                    app.aMax.Value = 600;
                    aSliderValue = 1;
                    app.aAnimationButton.Value = 1;
                    % b
                    app.bMin.Value = 5;
                    app.bMax.Value = 32;
                    bSliderValue = 5;
                    app.bAnimationButton.Value = 1;
                    % c
                    app.cMin.Value = 9;
                    app.cMax.Value = 20;
                    cSliderValue = 9;
                    app.cAnimationButton.Value = 0;
                    
                    % skale osi
                    % x
                    app.xMinEditField.Value = -1.57;
                    app.xMaxEditField.Value = 1.57;                    
                    % y funkcja
                    app.yMinEditField.Value = 0;
                    app.yMaxEditField.Value = 1;
                    % y pochodna
                    app.dFdxMinEditField.Value = -40;
                    app.dFdxMaxEditField.Value = 40;
            end
            % zmienia światła przy kierunku prędkości
            lightsSpeed(app);

            % ustawia limity suwaków pod pola tekstowe
            aSliderMatch(app);
            bSliderMatch(app);
            cSliderMatch(app);
            
            % ustawia suwaki na min
            app.aSlider.Value = aSliderValue;
            app.bSlider.Value = bSliderValue;
            app.cSlider.Value = cSliderValue;

            % pobiera wartości parametrów z suwaków
            app.aGlobal = app.aSlider.Value;
            app.bGlobal = app.bSlider.Value;
            app.cGlobal = app.cSlider.Value;

            formulaUpdate(app);

            % ustawia granice osi x i aktualizuje wykresy oraz ich nazwy
            xLimits(app);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % steruje swiatłami przy kierunku prędkości
        function lightsSpeed(app)
            if app.directionSwitch.ValueIndex == 1
                        app.upLamp.Color = '#b845ff';
                        app.downLamp.Color = '#a6a6a6';
                    else
                        app.upLamp.Color = '#a6a6a6';
                        app.downLamp.Color = '#b845ff';
            end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    end % koniec metod
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % wykonują się raz na początku programu
            % ustawia suwaki na ich najmniejsze wartości
            app.aSlider.Value = app.aSlider.Limits(1);
            app.bSlider.Value = app.bSlider.Limits(1);
            app.cSlider.Value = app.cSlider.Limits(1);

            % wczytuje pierwszy pakiet 9i wywołuje formulaUpdate)
            value = 1;
            packageSet(app, value);

            % nazwa wykresu
            chartName(app);
        end

        % Value changed function: formulaEditField
        function formulaEditFieldValueChanged(app, event)
        % gdy użytkownik wpisze coś w pole tekstowe i wciśnie enter
            formulaUpdate(app);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
                % gdy suwak "a" zostanue przesuniety
                
                % odczytuje wartość parametru a
                app.aGlobal = event.Value;
                % uruchamia funkcję aktualizującą wykres
                formulaUpdate(app);
                drawnow;

        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
                % gdy suwak "b" zostanue przesuniety
     
                % odczytuje wartość parametru b
                app.bGlobal = event.Value;
                % uruchamia funkcję aktualizującą wykres
                formulaUpdate(app);
                drawnow;
            
        end

        % Value changing function: cSlider
        function cSliderValueChanging(app, event)
                % gdy suwak "c" zostanue przesuniety
     
                % odczytuje wartość parametru c
                app.cGlobal = event.Value;
                % uruchamia funkcję aktualizującą wykres
                formulaUpdate(app);
                drawnow;

        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
                % przesuwa pozostałe suwaki, które biorą udział w
                % równoczesnym przesuwaniu
                value = app.aSlider.Value;
                if app.aGroupButton.Value
                    % pobiera wartości przesuwanego suwaka
                    min = app.aMin.Value;
                    max = app.aMax.Value;

                    if app.bGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.bMin.Value;
                        max2 = app.bMax.Value;
                        app.bSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                    if app.cGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.cMin.Value;
                        max2 = app.cMax.Value;
                        app.cSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                end
                drawnow;
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            % przesuwa pozostałe suwaki, które biorą udział w
                % równoczesnym przesuwaniu
                value = app.bSlider.Value;
                if app.bGroupButton.Value
                    % pobiera wartości przesuwanego suwaka
                    min = app.bMin.Value;
                    max = app.bMax.Value;

                    if app.aGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.aMin.Value;
                        max2 = app.aMax.Value;
                        app.aSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                    if app.cGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.cMin.Value;
                        max2 = app.cMax.Value;
                        app.cSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                end
                drawnow;
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            % przesuwa pozostałe suwaki, które biorą udział w
                % równoczesnym przesuwaniu
                value = app.cSlider.Value;
                if app.cGroupButton.Value
                    % pobiera wartości przesuwanego suwaka
                    min = app.cMin.Value;
                    max = app.cMax.Value;

                    if app.bGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.bMin.Value;
                        max2 = app.bMax.Value;
                        app.bSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                    if app.aGroupButton.Value
                        % pobiera zakresy z mienianego parametru
                        min2 = app.aMin.Value;
                        max2 = app.aMax.Value;
                        app.aSlider.Value = (value-min)/(max-min)*(max2-min2)+min2;
                    end
                end
                drawnow;
        end

        % Value changed function: aMax
        function aMaxValueChanged(app, event)
            aSliderMatch(app);
        end

        % Value changed function: aMin
        function aMinValueChanged(app, event)
            aSliderMatch(app);
        end

        % Value changed function: bMax
        function bMaxValueChanged(app, event)
            bSliderMatch(app);
        end

        % Value changed function: bMin
        function bMinValueChanged(app, event)
            bSliderMatch(app);
        end

        % Value changed function: cMax
        function cMaxValueChanged(app, event)
            cSliderMatch(app);
        end

        % Value changed function: cMin
        function cMinValueChanged(app, event)
            cSliderMatch(app);
        end

        % Value changed function: animationButton
        function animationButtonValueChanged(app, event)
            % puszcza animację, pod warunkiem, że którykolwiek parametr z
            % a, b, c bierze w niej udział
            war = app.animationButton.Value;
            if war && ~(...
                    app.aAnimationButton.Value == 0 && ...
                    app.bAnimationButton.Value == 0 && ...
                    app.cAnimationButton.Value == 0)
                app.animationButton.Text = 'Animacja {ON}';
                animation(app);
            else % wyłącza guzik, kiedy nie udało się zacząć animacji
                app.animationButton.Value = 0;
                war = 0;
            end
            if war == 0
                app.animationButton.Text = 'Animacja {OFF}';
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.formulaEditField.Value = '';
        end

        % Value changed function: xMinEditField
        function xMinEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.xCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.xMinEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.xMaxEditField.Value = 0;
                    xSym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                xLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: xMaxEditField
        function xMaxEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.xCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.xMaxEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.xMinEditField.Value = 0;
                    xSym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                xLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: yMinEditField
        function yMinEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.yCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.yMinEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.yMaxEditField.Value = 0;
                    ySym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                yLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: yMaxEditField
        function yMaxEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.yCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.yMaxEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.yMinEditField.Value = 0;
                    ySym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                yLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: dFdxMinEditField
        function dFdxMinEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.yCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.dFdxMinEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.dFdxMaxEditField.Value = 0;
                    dFdxSym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                dFdxLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: dFdxMaxEditField
        function dFdxMaxEditFieldValueChanged(app, event)
            % ustawia symetryczne granice, jeśli wybrana jest taka opcja
            if app.yCheckBox.Value
                % wykonuje ustawienie symetrii, kiedy podana wartość jest
                % różna od 0
                if app.dFdxMaxEditField.Value % to jest zagnieżdżony if, żeby na pewno nie wykonał się poniższy else, jeśli ustawiono symetryczne przedziały
                    % ustawia drugie pole na 0, by jego moduł był mniejszy od
                    % pierwszego
                    app.dFdxMinEditField.Value = 0;
                    dFdxSym(app);
                end
            else %jeśli nie, ustawia granice w normalny sposób
                dFdxLimits(app);
            end
            % zatrzymuje animację
            app.animationButton.Value = 0;
            app.animationButton.Text = 'Animacja {OFF}';
            % aktualizuje wykres
            formulaUpdate(app);
        end

        % Value changed function: directionSwitch
        function directionSwitchValueChanged(app, event)
            light = app.directionSwitch.ValueIndex;
            lightsSpeed(app);
        end

        % Button pushed function: actualizationButton
        function actualizationButtonPushed(app, event)
            % gdy użytkownik wpisze coś w pole tekstowe i wciśnie
            % "Aktualizacja"
            formulaUpdate(app);
        end

        % Value changed function: chartsMatchCheckBox
        function chartsMatchCheckBoxValueChanged(app, event)
            % ustawia taki sam zakres dla obu wartości
            value = app.chartsMatchCheckBox.Value;
            if value
                chartsMatch(app);
                app.yAutoSwitch.ValueIndex = 1;
            end
            
        end

        % Value changed function: yAutoSwitch
        function yAutoSwitchValueChanged(app, event)
            value = app.yAutoSwitch.ValueIndex;
            if value == 2
                app.chartsMatchCheckBox.Value = 0;
            end
            formulaUpdate(app);
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            if app.xCheckBox.Value
                xSym(app);
            else
                xLimits(app);
            end
        end

        % Value changed function: yCheckBox
        function yCheckBoxValueChanged(app, event)
            if app.yCheckBox.Value
                ySym(app);
                dFdxSym(app);
            else
                yLimits(app);
                dFdxLimits(app);
            end
        end

        % Value changed function: PrzykladyDropDown
        function PrzykladyDropDownValueChanged(app, event)
            % ustawia przygotowane funkcje
            value = app.PrzykladyDropDown.ValueIndex;
            packageSet(app, value);
        end

        % Value changed function: aAnimationButton
        function aAnimationButtonValueChanged(app, event)
            value = app.aAnimationButton.Value;
            if value
                app.aGroupButton.Value = 0;
            end
        end

        % Value changed function: bAnimationButton
        function bAnimationButtonValueChanged(app, event)
            value = app.bAnimationButton.Value;
            if value
                app.bGroupButton.Value = 0;
            end
        end

        % Value changed function: cAnimationButton
        function cAnimationButtonValueChanged(app, event)
            value = app.cAnimationButton.Value;
            if value
                app.cGroupButton.Value = 0;
            end
        end

        % Value changed function: aGroupButton
        function aGroupButtonValueChanged(app, event)
            value = app.aGroupButton.Value;
            if value
                app.aAnimationButton.Value = 0;
            end
        end

        % Value changed function: bGroupButton
        function bGroupButtonValueChanged(app, event)
            value = app.bGroupButton.Value;
            if value
                app.bAnimationButton.Value = 0;
            end
        end

        % Value changed function: cGroupButton
        function cGroupButtonValueChanged(app, event)
            value = app.cGroupButton.Value;
            if value
                app.cAnimationButton.Value = 0;
            end
        end

        % Button pushed function: OaplikacjiButton
        function OaplikacjiButtonPushed(app, event)
            msgbox({...
                'Aplikacja Wykreślacz', ...
                'autor: Łukasz Puchała', ...
                '', ...
                ['Aplikacja powstała w celu umożliwienia szybkiej wizualizacji przebiegu zmienności funkcji oraz jej pierwszej ' ...
                'pochodnej w zależności ' ...
                'od parametrów. Może się to okazać pomocne w lepszym zrozumieniu wielu procesów fizycznych, które zależą' ...
                'od różnych czynników i trudno jest przedstawić większy zakres zjawisk, które opisują, na jednym wykresie.'], ...
                ' ', ...
                ['Wykreślacz pozwala na wprowadzenie własnej formuły funkcji i wizualizację zmienności jej wykresu. Aby' ...
                'lepiej zaznajomić się z działaniem aplikacji, dobrze jest przetestować 3 wbudowane formuły, dostępne jako ' ...
                '"Przykłady". Podczas wpisywania własnej formuły, można podać jedynie następujące zmienne: "x", czyli ' ...
                'argument funkcji oraz "a", "b", "c", będące parametrami. Wartości parametrów można zmieniać przesuwając ' ...
                'odpowiednie suwaki, których zakresy są możliwe do ustalenia. Istnieje możliwość włączenia animacji, ' ...
                'która zmienia te parametry w czasie. Aplikacja umożliwia zmianę zakresu osi wykresów oraz kilka innych ' ...
                'przydatnych opcji, które widnieją w interfejsie graficznym.']
                }, ...
                'O aplikacji','help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WYKRELACZLabel = uilabel(app.UIFigure);
            app.WYKRELACZLabel.Text = 'WYKREŚLACZ';
            app.PrzykladyDropDownLabel = uilabel(app.UIFigure);
            app.PrzykladyDropDownLabel.Text = 'Przyklady';
            app.PrzykladyDropDown = uidropdown(app.UIFigure);
            app.PrzykladyDropDown.Items = {'Fale (x-polozenie, a,b,c-zmienne w czasie)', 'Rozklad Maxwella (x-predkosc, a-temperatura, b-masa)', 'Rezonans mechaniczny (x-czest. wym., a-tlumienie, b-masa, c-wsp. spr.)', 'Ciepło właściwe CiaSta (x-temperatura, a-czest. drgan)', 'Interferencja na 2 szczelinach (x-kat padnia na ekran, a-odl. miedz. szcz., b-szerok. szczel., c-dl. fali)'};
            app.PrzykladyDropDown.ValueChangedFcn = createCallbackFcn(app, @PrzykladyDropDownValueChanged, true);
            app.PrzykladyDropDown.Value = 'Fale (x-polozenie, a,b,c-zmienne w czasie)';
            app.aPanel = uipanel(app.UIFigure);
            app.aPanel.Title = 'a';
            app.aMax = uieditfield(app.aPanel, 'numeric');
            app.aMax.ValueChangedFcn = createCallbackFcn(app, @aMaxValueChanged, true);
            app.aMax.Value = 60;
            app.aSlider = uislider(app.aPanel);
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aMin = uieditfield(app.aPanel, 'numeric');
            app.aMin.ValueChangedFcn = createCallbackFcn(app, @aMinValueChanged, true);
            app.aMin.Value = -60;
            app.aAnimationButton = uibutton(app.aPanel, 'state');
            app.aAnimationButton.ValueChangedFcn = createCallbackFcn(app, @aAnimationButtonValueChanged, true);
            app.aAnimationButton.Text = {'Udzial'; 'w animacji'};
            app.aAnimationButton.Value = true;
            app.aGroupButton = uibutton(app.aPanel, 'state');
            app.aGroupButton.ValueChangedFcn = createCallbackFcn(app, @aGroupButtonValueChanged, true);
            app.aGroupButton.Text = {'Równoczesne'; 'ustawianie'};
            app.cPanel = uipanel(app.UIFigure);
            app.cPanel.Title = 'c';
            app.cMax = uieditfield(app.cPanel, 'numeric');
            app.cMax.ValueChangedFcn = createCallbackFcn(app, @cMaxValueChanged, true);
            app.cMin = uieditfield(app.cPanel, 'numeric');
            app.cMin.ValueChangedFcn = createCallbackFcn(app, @cMinValueChanged, true);
            app.cMin.Value = -10;
            app.cAnimationButton = uibutton(app.cPanel, 'state');
            app.cAnimationButton.ValueChangedFcn = createCallbackFcn(app, @cAnimationButtonValueChanged, true);
            app.cAnimationButton.Text = {'Udział '; 'w animacji'};
            app.cAnimationButton.Value = true;
            app.cSlider = uislider(app.cPanel);
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.cGroupButton = uibutton(app.cPanel, 'state');
            app.cGroupButton.ValueChangedFcn = createCallbackFcn(app, @cGroupButtonValueChanged, true);
            app.cGroupButton.Text = {'Równoczesne'; 'ustawianie'};
            app.bPanel = uipanel(app.UIFigure);
            app.bPanel.Title = 'b';
            app.bMax = uieditfield(app.bPanel, 'numeric');
            app.bMax.ValueChangedFcn = createCallbackFcn(app, @bMaxValueChanged, true);
            app.bMax.Value = 120;
            app.bMin = uieditfield(app.bPanel, 'numeric');
            app.bMin.ValueChangedFcn = createCallbackFcn(app, @bMinValueChanged, true);
            app.bAnimationButton = uibutton(app.bPanel, 'state');
            app.bAnimationButton.ValueChangedFcn = createCallbackFcn(app, @bAnimationButtonValueChanged, true);
            app.bAnimationButton.Text = {'Udział '; 'w animacji'};
            app.bAnimationButton.Value = true;
            app.bSlider = uislider(app.bPanel);
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bGroupButton = uibutton(app.bPanel, 'state');
            app.bGroupButton.ValueChangedFcn = createCallbackFcn(app, @bGroupButtonValueChanged, true);
            app.bGroupButton.Text = {'Równoczesne'; 'ustawianie'};
            app.Panel = uipanel(app.UIFigure);
            app.animationButton = uibutton(app.Panel, 'state');
            app.animationButton.ValueChangedFcn = createCallbackFcn(app, @animationButtonValueChanged, true);
            app.animationButton.Text = 'Animacja {OFF}';
            app.Panel_2 = uipanel(app.Panel);
            app.directionSwitch = uiswitch(app.Panel_2, 'slider');
            app.directionSwitch.Items = {'Rosnaca', 'Malejaca'};
            app.directionSwitch.ValueChangedFcn = createCallbackFcn(app, @directionSwitchValueChanged, true);
            app.directionSwitch.Value = 'Rosnaca';
            app.upLamp = uilamp(app.Panel_2);
            app.downLamp = uilamp(app.Panel_2);
            app.Panel_3 = uipanel(app.Panel);
            app.Szybkozmian1zakres10sLabel = uilabel(app.Panel_3);
            app.Szybkozmian1zakres10sLabel.Text = 'Szybkosc zmian ';
            app.speedEditField = uieditfield(app.Panel_3, 'numeric');
            app.speedEditField.Value = 7;
            app.Panel_6 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'F')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesD = uiaxes(app.Panel_6);
            title(app.UIAxesD, 'F''')
            zlabel(app.UIAxesD, 'Z')
            app.Panel_5 = uipanel(app.Panel_6);
            app.ZakresosixLabel = uilabel(app.Panel_5);
            app.ZakresosixLabel.Text = 'Zakres osi x';
            app.xMinEditField = uieditfield(app.Panel_5, 'numeric');
            app.xMinEditField.ValueChangedFcn = createCallbackFcn(app, @xMinEditFieldValueChanged, true);
            app.xMinEditField.Value = -1.5;
            app.xMaxEditField = uieditfield(app.Panel_5, 'numeric');
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @xMaxEditFieldValueChanged, true);
            app.xMaxEditField.Value = 1.5;
            app.Panel_4 = uipanel(app.Panel_6);
            app.ZakresosiyLabel = uilabel(app.Panel_4);
            app.ZakresosiyLabel.Text = 'Zakres osi y';
            app.yMaxEditField = uieditfield(app.Panel_4, 'numeric');
            app.yMaxEditField.ValueChangedFcn = createCallbackFcn(app, @yMaxEditFieldValueChanged, true);
            app.yMaxEditField.Value = 1.1;
            app.yMinEditField = uieditfield(app.Panel_4, 'numeric');
            app.yMinEditField.ValueChangedFcn = createCallbackFcn(app, @yMinEditFieldValueChanged, true);
            app.yMinEditField.Value = -1.1;
            app.dFdxMaxEditField = uieditfield(app.Panel_4, 'numeric');
            app.dFdxMaxEditField.ValueChangedFcn = createCallbackFcn(app, @dFdxMaxEditFieldValueChanged, true);
            app.dFdxMaxEditField.Value = 60;
            app.dFdxMinEditField = uieditfield(app.Panel_4, 'numeric');
            app.dFdxMinEditField.ValueChangedFcn = createCallbackFcn(app, @dFdxMinEditFieldValueChanged, true);
            app.dFdxMinEditField.Value = -60;
            app.Panel_7 = uipanel(app.UIFigure);
            app.PARAMETRYLabel = uilabel(app.Panel_7);
            app.PARAMETRYLabel.Text = 'PARAMETRY';
            app.Panel_11 = uipanel(app.UIFigure);
            app.FxLabel = uilabel(app.Panel_11);
            app.FxLabel.Text = 'F(x)=';
            app.actualizationButton = uibutton(app.Panel_11, 'push');
            app.actualizationButton.ButtonPushedFcn = createCallbackFcn(app, @actualizationButtonPushed, true);
            app.actualizationButton.Text = 'Aktualizacja';
            app.xButton = uibutton(app.Panel_11, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.ProszwprowadzifunkcjLabel = uilabel(app.Panel_11);
            app.ProszwprowadzifunkcjLabel.Text = 'Prosze wprowadzic funkcje';
            app.formulaEditField = uieditfield(app.Panel_11, 'text');
            app.formulaEditField.ValueChangedFcn = createCallbackFcn(app, @formulaEditFieldValueChanged, true);
            app.formulaEditField.Value = 'sin(b+a*x)*exp(-c*-x^2)';
            app.OaplikacjiButton = uibutton(app.UIFigure, 'push');
            app.OaplikacjiButton.ButtonPushedFcn = createCallbackFcn(app, @OaplikacjiButtonPushed, true);
            app.OaplikacjiButton.Text = 'O aplikacji';
            app.Panel_12 = uipanel(app.UIFigure);
            app.Label_2 = uilabel(app.Panel_12);
            app.Label_2.Text = '';
            app.SkalaosiydlaobuwykreswPanel = uipanel(app.Panel_12);
            app.SkalaosiydlaobuwykreswPanel.Title = 'Skala osi y dla obu wykresów';
            app.Label = uilabel(app.SkalaosiydlaobuwykreswPanel);
            app.Label.Text = '';
            app.yAutoSwitch = uiswitch(app.SkalaosiydlaobuwykreswPanel, 'slider');
            app.yAutoSwitch.Items = {'Ustawiona', 'Automatyczna'};
            app.yAutoSwitch.ValueChangedFcn = createCallbackFcn(app, @yAutoSwitchValueChanged, true);
            app.yAutoSwitch.Value = 'Ustawiona';
            app.Panel_9 = uipanel(app.Panel_12);
            app.chartsMatchCheckBox = uicheckbox(app.Panel_9);
            app.chartsMatchCheckBox.ValueChangedFcn = createCallbackFcn(app, @chartsMatchCheckBoxValueChanged, true);
            app.chartsMatchCheckBox.Text = 'Taki sam zakres y dla obu wykresów';
            app.Panel_10 = uipanel(app.Panel_12);
            app.SymetrycznyzakresLabel = uilabel(app.Panel_10);
            app.SymetrycznyzakresLabel.Text = 'Symetryczny zakres:';
            app.xCheckBox = uicheckbox(app.Panel_10);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.yCheckBox = uicheckbox(app.Panel_10);
            app.yCheckBox.ValueChangedFcn = createCallbackFcn(app, @yCheckBoxValueChanged, true);
            app.yCheckBox.Text = 'y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wykreslacz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end